classdef robot_arm_sim_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LynxArmSimUIFigure              matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        DesiredPositionLabel            matlab.ui.control.Label
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        ZSpinner                        matlab.ui.control.Spinner
        Panel_2                         matlab.ui.container.Panel
        CurrentObstacleMapLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MapDropDown                     matlab.ui.control.DropDown
        StopArmButton                   matlab.ui.control.Button
        watchhowthegreenspheremovesLabel  matlab.ui.control.Label
        asyouchangethevaluesoftheLabel  matlab.ui.control.Label
        desiredpositionLabel            matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        BeginArmMovementButton          matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        LynxArmmovementsimulatorLabel   matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        StoppingDistancecmSliderLabel   matlab.ui.control.Label
        StoppingDistancecmSlider        matlab.ui.control.Slider
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global currentconf 
            global plottedPath
            global currentmapname
            
            currentconf = [0 0 0 0];
            plottedPath = "";
            currentmapname = "";
            MapDropDownValueChanged(app,[]);
            StoppingDistancecmSliderValueChanged(app, [])
             
        end

        % Button pushed function: BeginArmMovementButton
        function BeginArmMovementButtonPushed(app, event)

            global currentmap 
            global currentconf
            global plottedPath
            global currentlyShowingPath
            global stoppingDistance
	
            if ~isstring(plottedPath) && ~isempty(plottedPath)
                deletePointsFromPlot(plottedPath)
            end
           
            goal = [app.XSpinner.Value  app.YSpinner.Value  app.ZSpinner.Value];
            path = findpath(currentmap, currentconf, goal, stoppingDistance);
            if ~isstring(path)             
                
                app.MapDropDown.Enable = false;
                app.BeginArmMovementButton.Enable = false;
                app.StopArmButton.Enable = true;
                
                plottedPath = showPath(path); 
            else
                currentconf = [0 0 0 0];
                plotLynx([currentconf 0]);
                errordlg(path)
                plottedPath = "";
            end
            
            app.MapDropDown.Enable = true;
            app.BeginArmMovementButton.Enable = true;
            app.StopArmButton.Enable = false;
            
            currentlyShowingPath = false;
            

        end

        % Button pushed function: StopArmButton
        function StopArmButtonPushed(app, event)
            global currentlyShowingPath
            currentlyShowingPath = false;
            app.MapDropDown.Enable = true;
            app.BeginArmMovementButton.Enable = true;
            app.StopArmButton.Enable = false;
        end

        % Value changed function: MapDropDown
        function MapDropDownValueChanged(app, event)
            global currentmap 
            global currentconf
            global currentmapname
            global plottedPath
            fileSelected = app.MapDropDown.Value;
            if ~strcmp(currentmapname, fileSelected)
                currentmapname = fileSelected;
                fileSelected = strcat(fileSelected,".txt");
                currentmap = loadmap(fileSelected);
                
                lynxStart();
                plotmap(currentmap);
                plotLynx([currentconf 0]);
                plottedPath = [];
                XSpinnerValueChanged(app, event);
            end
        end

        % Callback function: XSpinner, YSpinner, ZSpinner
        function XSpinnerValueChanged(app, event)
            global plottedGoalPoint
    
            delete(plottedGoalPoint);
            
            plottedGoalPoint = plotGoal(app.XSpinner.Value,  app.YSpinner.Value,  app.ZSpinner.Value);
           
        end

        % Value changed function: StoppingDistancecmSlider
        function StoppingDistancecmSliderValueChanged(app, event)
            global stoppingDistance
            stoppingDistance = app.StoppingDistancecmSlider.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LynxArmSimUIFigure = uifigure;
            app.LynxArmSimUIFigure.Name = 'Lynx Arm Sim';
            app.Panel = uipanel(app.LynxArmSimUIFigure);
            app.DesiredPositionLabel = uilabel(app.Panel);
            app.DesiredPositionLabel.Text = 'Desired Position';
            app.XSpinnerLabel = uilabel(app.Panel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.Panel);
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Limits = [-400 400];
            app.XSpinner.ValueDisplayFormat = '%.2f';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Value = 292.1;
            app.YSpinnerLabel = uilabel(app.Panel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.Panel);
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinner.Limits = [-400 400];
            app.YSpinner.ValueDisplayFormat = '%.2f';
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.Panel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.Panel);
            app.ZSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.ZSpinner.Limits = [-300 500];
            app.ZSpinner.ValueDisplayFormat = '%.2f';
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.ZSpinner.Value = 222.25;
            app.Panel_2 = uipanel(app.LynxArmSimUIFigure);
            app.CurrentObstacleMapLabel = uilabel(app.Panel_2);
            app.CurrentObstacleMapLabel.Text = 'Current Obstacle Map';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.MapDropDown = uidropdown(app.Panel_2);
            app.MapDropDown.Items = {'map_1', 'map_2', 'map_3', 'map_4', 'map_5'};
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @MapDropDownValueChanged, true);
            app.MapDropDown.Value = 'map_1';
            app.StopArmButton = uibutton(app.Panel_2, 'push');
            app.StopArmButton.ButtonPushedFcn = createCallbackFcn(app, @StopArmButtonPushed, true);
            app.StopArmButton.Enable = 'off';
            app.StopArmButton.Text = 'Stop Arm';
            app.watchhowthegreenspheremovesLabel = uilabel(app.Panel_2);
            app.watchhowthegreenspheremovesLabel.Text = 'watch how the green sphere moves';
            app.asyouchangethevaluesoftheLabel = uilabel(app.Panel_2);
            app.asyouchangethevaluesoftheLabel.Text = 'as you change the values of the';
            app.desiredpositionLabel = uilabel(app.Panel_2);
            app.desiredpositionLabel.Text = 'desired position';
            app.Panel_3 = uipanel(app.LynxArmSimUIFigure);
            app.BeginArmMovementButton = uibutton(app.Panel_3, 'push');
            app.BeginArmMovementButton.ButtonPushedFcn = createCallbackFcn(app, @BeginArmMovementButtonPushed, true);
            app.BeginArmMovementButton.Text = 'Begin Arm Movement';
            app.Panel_4 = uipanel(app.LynxArmSimUIFigure);
            app.LynxArmmovementsimulatorLabel = uilabel(app.Panel_4);
            app.LynxArmmovementsimulatorLabel.Text = 'Lynx Arm movement simulator';
            app.Panel_5 = uipanel(app.LynxArmSimUIFigure);
            app.StoppingDistancecmSliderLabel = uilabel(app.Panel_5);
            app.StoppingDistancecmSliderLabel.Text = 'Stopping Distance (cm)';
            app.StoppingDistancecmSlider = uislider(app.Panel_5);
            app.StoppingDistancecmSlider.Limits = [1 9];
            app.StoppingDistancecmSlider.ValueChangedFcn = createCallbackFcn(app, @StoppingDistancecmSliderValueChanged, true);
            app.StoppingDistancecmSlider.Value = 4;
        end
    end

    methods (Access = public)

        % Construct app
        function app = robot_arm_sim_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LynxArmSimUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LynxArmSimUIFigure)
        end
    end
end